classdef nd2Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FileMenu            matlab.ui.container.Menu
        OpenMenu            matlab.ui.container.Menu
        CloseMenu           matlab.ui.container.Menu
        UIAxes              matlab.ui.control.UIAxes
        ChannelSliderLabel  matlab.ui.control.Label
        ChannelSlider       matlab.ui.control.Slider
        TimeSliderLabel     matlab.ui.control.Label
        TimeSlider          matlab.ui.control.Slider
        PlayButton          matlab.ui.control.StateButton
    end


    properties (Access = private)
        OpenFile = ''; % Description
        bfReader;
    end

    methods (Access = private)
    
        function updateImage(app)
            
            imshow(app.bfReader.getPlane(1, app.ChannelSlider.Value, app.TimeSlider.Value), [], 'Parent', app.UIAxes);
            drawnow;
        end
        
    end


    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            
            [filename, pathname] = uigetfile({'*.nd2', 'ND2 file (*.nd2)'},'Select an image file');
            
            if ~ischar(filename)
                return;
            end
            
            app.OpenFile = fullfile(pathname,filename);
            
            app.bfReader = BioformatsImage(app.OpenFile);
            
            app.ChannelSlider.Limits = [1, app.bfReader.sizeC];
            app.ChannelSlider.MajorTicks = 1:app.bfReader.sizeC;
            
            app.TimeSlider.Limits = [1, app.bfReader.sizeT];
            app.TimeSlider.MajorTicks = 1:app.bfReader.sizeT;
            
            app.updateImage;
            
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            app.OpenFile = '';
            app.bfReader = [];
        end

        % Value changed function: ChannelSlider
        function ChannelSliderValueChanged(app, event)
            
            %Round the value
            app.ChannelSlider.Value = round(app.ChannelSlider.Value);
            
            app.updateImage;
            
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            app.TimeSlider.Value = round(app.TimeSlider.Value);
            
            app.updateImage;
        end

        % Callback function
        function PlayButtonPushed(app, event)

        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            
            if value
                
                app.PlayButton.Text = 'Stop';
                app.TimeSlider.Enable = 'off';
                app.ChannelSlider.Enable = 'off';
                
                while app.PlayButton.Value && app.TimeSlider.Value <= app.bfReader.sizeT
                    app.TimeSlider.Value = app.TimeSlider.Value + 1;
                    app.updateImage;
                end
                
            else
                
                app.PlayButton.Text = 'Play';
                app.TimeSlider.Enable = 'on';
                app.ChannelSlider.Enable = 'on';
                
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ChannelSliderLabel = uilabel(app.UIFigure);
            app.ChannelSliderLabel.Text = 'Channel';
            app.ChannelSlider = uislider(app.UIFigure);
            app.ChannelSlider.ValueChangedFcn = createCallbackFcn(app, @ChannelSliderValueChanged, true);
            app.ChannelSlider.MinorTicks = [];
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.MajorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
        end
    end

    methods (Access = public)

        % Construct app
        function app = nd2Viewer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end